classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Button            matlab.ui.control.Button
        Label_2           matlab.ui.control.Label
        EditFunction      matlab.ui.control.EditField
        ButtonGroup       matlab.ui.container.ButtonGroup
        CheckBoxFindRoot  matlab.ui.control.CheckBox
        CheckBoxFindMin   matlab.ui.control.CheckBox
        CheckBoxFindMax   matlab.ui.control.CheckBox
        Label_9           matlab.ui.control.Label
        EditField_2Label  matlab.ui.control.Label
        EditRangeMin      matlab.ui.control.NumericEditField
        Label_10          matlab.ui.control.Label
        EditRangeMax      matlab.ui.control.NumericEditField
        CenterPanel       matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        RightPanel        matlab.ui.container.Panel
        LabelRoot         matlab.ui.control.Label
        LabelMin          matlab.ui.control.Label
        LabelMax          matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: EditFunction
        function EditFunctionValueChanging(app, event)
            changingValue = event.Value;            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            y=app.EditFunction.Value;
            fplot(app.UIAxes,y,[-1 1]);
            grid (app.UIAxes,'on');
            a=app.EditRangeMin.Value;
            b=app.EditRangeMax.Value;
            m=100;
            x=linspace(a,b,m);
            %root
            if app.CheckBoxFindRoot.Value==1
                plot(x, eval(y), x, 0*x,':');
                z=ginput(1);
                [zr, fr]=fzero(y, z(1))
                hold on
                plot(zr, fr, 'r*', z(1), z(2), 'g*')
                
                hold off
                app.LabelRoot.Text=num2str(zr);
            end
            if app.CheckBoxFindRoot.Value==0
                app.LabelRoot.Text='-';
            end
            %min
            if app.CheckBoxFindMin.Value==1
               syms x
               func=str2sym(y);
               s=solve(diff(func));               
               s = s(and(s >= a, s <= b));
               s = [s; a; b];
               cpv = eval(subs(func, x, s));
               minValue = min(cpv);
               app.LabelMin.Text = num2str(minValue);
             end
            if app.CheckBoxFindMin.Value==0
               app.LabelMin.Text='-'; 
            end
            %max
            if app.CheckBoxFindMax.Value==1
                syms x
               func=str2sym(y);
               s=solve(diff(func));               
               s = s(and(s >= a, s <= b));
               s = [s; a; b];
               cpv = eval(subs(func, x, s));
               maxValue = max(cpv);
               app.LabelMax.Text = num2str(maxValue);
            end
            if app.CheckBoxFindMax.Value==0
                app.LabelMax.Text='-';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x', 189};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'Найти'; ''};
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Введите функцию:';
            app.EditFunction = uieditfield(app.LeftPanel, 'text');
            app.EditFunction.ValueChangingFcn = createCallbackFcn(app, @EditFunctionValueChanging, true);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Title = 'Трубуется найти:';
            app.CheckBoxFindRoot = uicheckbox(app.ButtonGroup);
            app.CheckBoxFindRoot.Text = 'Корень';
            app.CheckBoxFindMin = uicheckbox(app.ButtonGroup);
            app.CheckBoxFindMin.Text = 'Точка минимума';
            app.CheckBoxFindMax = uicheckbox(app.ButtonGroup);
            app.CheckBoxFindMax.Text = 'Точка максимума';
            app.Label_9 = uilabel(app.ButtonGroup);
            app.Label_9.Text = 'Введите промежуток:';
            app.EditField_2Label = uilabel(app.ButtonGroup);
            app.EditField_2Label.Text = 'от';
            app.EditRangeMin = uieditfield(app.ButtonGroup, 'numeric');
            app.Label_10 = uilabel(app.ButtonGroup);
            app.Label_10.Text = {'до '; ''};
            app.EditRangeMax = uieditfield(app.ButtonGroup, 'numeric');
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'График функции')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.LabelRoot = uilabel(app.RightPanel);
            app.LabelRoot.Text = ' ';
            app.LabelMin = uilabel(app.RightPanel);
            app.LabelMin.Text = '';
            app.LabelMax = uilabel(app.RightPanel);
            app.LabelMax.Text = '';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = 'Ответы:';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = 'Корень:';
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = 'Точка минимума:';
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Text = 'Точка максимума:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end